classdef Full_REM_dblswitch_ecd_mlfs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DN_0EditField                 matlab.ui.control.NumericEditField
        DN_0EditFieldLabel            matlab.ui.control.Label
        mu_rmEditField                matlab.ui.control.NumericEditField
        mu_rmEditFieldLabel           matlab.ui.control.Label
        AdditionalREMNREMinputsLabel  matlab.ui.control.Label
        DR_0EditField                 matlab.ui.control.NumericEditField
        DR_0EditFieldLabel            matlab.ui.control.Label
        nu_rhrEditField               matlab.ui.control.NumericEditField
        nu_rhrEditFieldLabel          matlab.ui.control.Label
        chi_rEditField                matlab.ui.control.NumericEditField
        chi_rEditFieldLabel           matlab.ui.control.Label
        REMHDriveLabel                matlab.ui.control.Label
        tau_rnEditField               matlab.ui.control.NumericEditField
        tau_rnEditFieldLabel          matlab.ui.control.Label
        mu_rnEditField                matlab.ui.control.NumericEditField
        mu_rnEditFieldLabel           matlab.ui.control.Label
        nu_nmEditField                matlab.ui.control.NumericEditField
        nu_nmEditFieldLabel           matlab.ui.control.Label
        nu_rmEditField                matlab.ui.control.NumericEditField
        nu_rmEditFieldLabel           matlab.ui.control.Label
        nu_nrEditField                matlab.ui.control.NumericEditField
        nu_nrEditFieldLabel           matlab.ui.control.Label
        nu_nvEditField                matlab.ui.control.NumericEditField
        nu_nvEditFieldLabel           matlab.ui.control.Label
        nu_rnEditField                matlab.ui.control.NumericEditField
        nu_rnEditFieldLabel           matlab.ui.control.Label
        REMNREMSwitchLabel            matlab.ui.control.Label
        lightprocessingLabel          matlab.ui.control.Label
        SleepWakeSwitchLabel          matlab.ui.control.Label
        bEditField                    matlab.ui.control.NumericEditField
        bEditFieldLabel               matlab.ui.control.Label
        mu_vmEditField                matlab.ui.control.NumericEditField
        mu_vmEditFieldLabel           matlab.ui.control.Label
        chi_vEditField                matlab.ui.control.NumericEditField
        chi_vEditFieldLabel           matlab.ui.control.Label
        D_0EditField                  matlab.ui.control.NumericEditField
        D_0EditFieldLabel             matlab.ui.control.Label
        nu_vcEditField                matlab.ui.control.NumericEditField
        nu_vcEditFieldLabel           matlab.ui.control.Label
        nu_vhEditField                matlab.ui.control.NumericEditField
        nu_vhEditFieldLabel           matlab.ui.control.Label
        nu_vmEditField                matlab.ui.control.NumericEditField
        nu_vmEditFieldLabel           matlab.ui.control.Label
        AmAChandOrexinEditField       matlab.ui.control.NumericEditField
        AmAChandOrexinEditFieldLabel  matlab.ui.control.Label
        nu_mvEditField                matlab.ui.control.NumericEditField
        nu_mvEditFieldLabel           matlab.ui.control.Label
        tau_vmEditField               matlab.ui.control.NumericEditField
        tau_vmEditFieldLabel          matlab.ui.control.Label
        UIAxes8                       matlab.ui.control.UIAxes
        UIAxes7                       matlab.ui.control.UIAxes
        UIAxes6                       matlab.ui.control.UIAxes
        UIAxes5                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        tau_vm = 10; % Property % Description
        nu_mv = -1.8;
        Am = 1.3;
        nu_vm = -2.1;
        nu_vh = 1.0;
        nu_vc = -3.37;
        D_0 = -10.2;
        chi_v = 45;
        mu_vm = 4.2;
        b = 1.0;
        nu_rn = -1.0;
        nu_nv = 2.0;
        nu_nr = -1.0;
        nu_rm = -2.0;
        nu_nm = 0.5;
        mu_rn = 1.0;
        tau_rn = 10;
        chi_r = 0.3;
        nu_rhr = 1.0;
        mu_rm = 0.5;
        DR_0 = 0;
        DN_0 = 0;
    end
    
    methods (Access = private)
        
        function [t,Yout,state_n,stab_match,stability] = mainfunc(app)
            
            T = 3*24;%14*24; % max time (h) to simulate
            %plotting = 1; % 1=yes for plotting figures
            x0 = [0.6,-6.7,-5,-5,14.6,0.35,-0.86,-0.59,5]'; % initial conditions (Vm,Vv,Vr,Vn,Hv,n,x,xc,Hr)
            
            %% Define defaut model parameter values
            % Values from Skeldon et al. (2017)
            
            sph = 3600; % seconds per hour (for unit conversions)
            
            % sleep/wake switch parameters
            p{1} = sph/app.tau_vm; % 1/tau_v=1/tau_m
            p{2} = app.nu_mv/sph;%-2.7/sph; % numv -1.8
            p{3} = app.Am;%3.6;%1.3; % )  % orexin
            p{4} = app.nu_vm/sph;%-2.0/sph; % -3.50/sph; % nuvm -2.0
            p{5} = app.nu_vh; % nuvh
            p{6} = app.nu_vc; %-1.0; %-3.37; % nuvc
            p{7} = app.D_0; % D_0
            p{8} = 1.0/app.chi_v; %1.0/18.0; %1.0/45.0; % 1/chi_v
            p{9} = app.mu_vm/sph; %8.20/sph; %4.20/sph; % mu % mu_vm
            
            % light processing parameters
            p{10} = 0.16; % alpha0
            p{11} = 9500; % I0
            p{12} = 0.6; % p
            p{13} = app.b; %1.0; %0.4; % b
            p{14} = 19.9; % G
            p{15} = 60; % lambda
            p{16} = 0.013; % beta
            
            % circadian pacemaker parameters
            tauc = 24.2; % Define intrinsic circadian period (h)
            p{17} = pi/12; %1/kappa
            p{18} = 0.23; %gamma
            p{19} = 4.0/3.0; % coefficient for x^3 term
            p{20} = (24.0/(0.99669*tauc))^2; % tau term
            p{21} = 0.55; % k
            
            % REM switch
            p{22} = app.nu_rn/sph; % nu_rn
            p{23} = app.nu_nv/sph; % nu_nv     2.0
            p{24} = app.nu_nr/sph; % nu_nr
            p{25} = sph/app.tau_rn; % 1/tau_r=1/tau_n
            p{26} = app.nu_rm/sph; % nu_rm
            p{27} = app.nu_nm/sph; % nu_nm
            
            % REM drive
            p{28} = app.mu_rn/sph; %1.10/sph; % mu % mu_rn
            p{29} = 1.0/app.chi_r; %1.0/0.5; % 1/chi_r6
            p{30} = app.nu_rhr; % nu_rhr
            
            % addition to sleep drive from REM 
            p{31} = 0.00/sph; % mu % mu_vr
            
            p{32} = app.mu_rm/sph; % mu_rm 
            p{33} = 0.0/sph; % mu_rv
            
            % additional inputs into 
            p{34} = app.DR_0; % DR_0
            p{35} = app.DN_0; % DN_0
            
            %% Solve the differential equations
            [~,Y] = ode23s(@Phillips_REM_dblswitch_ecd,[0,2*7*24],x0',odeset,p); % This uses 2 weeks to remove transients (generally don't ever need longer than 4 weeks, unless conditions are really messed up)
            Y=Y'; % Transpose of Y
            %disp('Finished transient')
            
            % Remove transient and solve again
            x0 = Y(:,end);
            [t,Y] = ode23s(@Phillips_REM_dblswitch_ecd,[0,T],x0',odeset,p);
            Y=Y'; % Transpose of Y
            %disp('Finished simulation')
            
            t = t-24*floor(t(1)/24); % Rezero time
            
            % Define a new time variable that goes a week longer for comparison of midsleeps with upcoming work times
            tshift = [t(1):0.01:t(end)+168];
            
            %% Define output variables
            
            Vm = Y(1,:); % Voltage of MA population
            Vv = Y(2,:); % Voltage of VLPO population
            Vr = Y(3,:); % Voltage of REM-Brainstem(GABA) SLD/PC population
            Vn = Y(4,:); % Voltage of NREM-Brainstem(GABA) vlPAG/LPT population
            H = Y(5,:); % Homeostatic drive to VLPO
            n = Y(6,:); % Photoreceptors
            x = Y(7,:); % Pacemaker variable 1
            xc = Y(8,:); % Pacemaker variable 2
            Hr = Y(9,:); % REM Homeostatic drive 
            
            Yout = Y;
            
            C = 0.5*(1+0.80*xc-0.47*x); % Circadian drive
            Dv = p{5}*H + p{6}*C + p{7}; % Overall drive to VLPO
            
            %% states
            
            state_n = repelem(1,length(Vm)); %m
            state_n(Vv > Vm & Vr > Vn) = 2; %r
            state_n(Vv > Vm & Vn >= Vr) = 3; %n
            state_lab = repelem("W",length(Vm)); %m
            state_lab(Vv > Vm & Vr > Vn) = "R"; %r
            state_lab(Vv > Vm & Vn >= Vr) = "NR"; %n
            
            primarystate_n = repelem(1,length(Vm)); %m
            primarystate_n(Vv > Vm) = 2; %v
            primarystate_lab = repelem("W",length(Vm)); %m
            primarystate_lab(Vv > Vm) = "S"; %v
            
            secondarystate_n = repelem(1,length(Vr)); %r
            secondarystate_n(Vn >= Vr) = 2; %n
            secondarystate_lab = repelem("R",length(Vr)); %r
            secondarystate_lab(Vn >= Vr) = "NR"; %n
            
            %% stability
            
            % 0 = -V_n + nu_nv * S(V_v) + nu_nr * S(nu_rn * S(V_n) + nu_rm * S(nu_mv * S(V_v) + A ) + nu_rHr * H_r ) + nu_nm * S(nu_mv * S(V_v ) + A)
            % x is Vv, y is Hr, z is Vn     
            % 0 = -z + p{23} * S(x) + p{24} * S(p{22} * S(z) + p{26} * S(p{2} * S(x) + p{3} ) + p{30} * y ) + p{27} * S(p{2} * S(x) + p{3})
            fstab = @(x,y,z) -z + p{23}.*sigmoid(x) + p{24}.*sigmoid(p{22}.*sigmoid(z) + p{26}.*sigmoid(p{2}.*sigmoid(x) + p{3}) + p{30}.*y + p{34}) + p{27}.*sigmoid(p{2}.*sigmoid(x) + p{3}) + p{35};

            x = -40:0.1:5;
            y = 0:0.5:120;
            z = -80:0.1:50;
            [X,Y,Z] = meshgrid(x,y,z);
            V = fstab(X,Y,Z);
            I = isosurface(x,y,z,V,0);
            
            Vm_match = p{2} .* sigmoid(I.vertices(:,1)) + p{3}; 
            % V_r = nu_rn * S(V_n) + nu_rm * S(V_m) + nu_rHr * H_r
            % V_r = p{22} * S(V_n) + p{26} * S(V_m) + p{30} * H_r
            Vr_match = p{22} .* sigmoid(I.vertices(:,3)) + p{26} .* sigmoid(Vm_match) + p{30} .* I.vertices(:,2) + p{34};
            
            Vv_match = I.vertices(:,1);
            Hr_match = I.vertices(:,2);
            Vn_match = I.vertices(:,3);
            
            stab_match = [Vv_match,Hr_match,Vn_match,Vm_match,Vr_match];
            stab_match = stab_match';
            
            fsp_ev{1} = p{25};
            fsp_ev{2} = p{22};
            fsp_ev{3} = p{24};
            
            % situation 
            stability = zeros(1,length(Vr_match));
            
            for ii = 1:length(Vr_match)
   
                %num_roots(ii) = length(fs_eigenvalues(fsp,Vm(ii),Vv(ii),Va(ii)));  
                num_roots(ii) = length(fs_RNR_eigenvalues_raw(fsp_ev,Vr_match(ii),Vn_match(ii)));  
                if num_roots(ii) == 2
                    %ev_vec(:,ii) = fs_eigenvalues(fsp,Vm(ii),Vv(ii),Va(ii)); 
                    ev_vec(:,ii) = fs_RNR_eigenvalues_raw(fsp_ev,Vr_match(ii),Vn_match(ii)); 
                elseif num_roots(ii) == 1
                    %ev_vec(:,ii) = [fs_eigenvalues(fsp,Vm(ii),Vv(ii),Va(ii)) NaN]; 
                    ev_vec(:,ii) = [fs_RNR_eigenvalues_raw(fsp_ev,Vr_match(ii),Vn_match(ii)) NaN]; 
                end
               
                pp = sum(ev_vec(:,ii));
                q = prod(ev_vec(:,ii));
                del = diff(ev_vec(:,ii))^2;
               
                if pp < 0 && q > 0 && del >= 0
                    stability(ii) = 1;
                elseif pp > 0 && q >0
                    stability(ii) = 2;
                elseif q < 0 
                    stability(ii) = 3;
                elseif pp == 0 && q > 0
                    stability(ii) = 4;
                elseif del < 0
                    stability(ii) = 5;
                end 
   
            end
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [t,Y,state_n,stab_match,stability] = mainfunc(app); 
            
            plot(app.UIAxes,t,Y(1,:),"r",t,Y(2,:),"b");xlabel(app.UIAxes,"Time");ylabel(app.UIAxes,"Resting Potential");
            title(app.UIAxes,"Sleep-Wake Switch");xticks(app.UIAxes,0:6:72)
            plot(app.UIAxes2,t,Y(3,:),"y",t,Y(4,:),"g");xlabel(app.UIAxes2,"Time");ylabel(app.UIAxes2,"Resting Potential");
            title(app.UIAxes2,"REM-NREM Switch");xticks(app.UIAxes2,0:6:72)
            plot(app.UIAxes3,t,state_n);set(app.UIAxes3,'YDir','reverse');yticks(app.UIAxes3,[1,2,3]);yticklabels(app.UIAxes3,{'Wake', 'REM','NREM'});
            xlabel(app.UIAxes3,"Time");ylabel(app.UIAxes3,"Sleep State");title(app.UIAxes3,"Hypnogram (Sleep State over Time)");xticks(app.UIAxes3,0:6:72)
            
            plot3(app.UIAxes5,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes5,'Vv');ylabel(app.UIAxes5,"Hr");zlabel(app.UIAxes5,"Vn")

            plot3(app.UIAxes6,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes6,'Vv');ylabel(app.UIAxes6,"Hr");zlabel(app.UIAxes6,"Vr")

            plot3(app.UIAxes7,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes7,'Vm');ylabel(app.UIAxes7,"Hr");zlabel(app.UIAxes7,"Vn")

            plot3(app.UIAxes8,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes8,'Vm');ylabel(app.UIAxes8,"Hr");zlabel(app.UIAxes8,"Vr")

            
        end

        % Value changed function: tau_vmEditField
        function tau_vmEditFieldValueChanged(app, event)
            app.tau_vm = app.tau_vmEditField.Value;
            
            [t,Y,state_n,stab_match,stability] = mainfunc(app); 
            
            plot(app.UIAxes,t,Y(1,:),"r",t,Y(2,:),"b");xlabel(app.UIAxes,"Time");ylabel(app.UIAxes,"Resting Potential");
            title(app.UIAxes,"Sleep-Wake Switch");xticks(app.UIAxes,0:6:72)
            plot(app.UIAxes2,t,Y(3,:),"y",t,Y(4,:),"g");xlabel(app.UIAxes2,"Time");ylabel(app.UIAxes2,"Resting Potential");
            title(app.UIAxes2,"REM-NREM Switch");xticks(app.UIAxes2,0:6:72)
            plot(app.UIAxes3,t,state_n);set(app.UIAxes3,'YDir','reverse');yticks(app.UIAxes3,[1,2,3]);yticklabels(app.UIAxes3,{'Wake', 'REM','NREM'});
            xlabel(app.UIAxes3,"Time");ylabel(app.UIAxes3,"Sleep State");title(app.UIAxes3,"Hypnogram (Sleep State over Time)");xticks(app.UIAxes3,0:6:72)

            plot3(app.UIAxes5,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes5,'Vv');ylabel(app.UIAxes5,"Hr");zlabel(app.UIAxes5,"Vn")

            plot3(app.UIAxes6,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes6,'Vv');ylabel(app.UIAxes6,"Hr");zlabel(app.UIAxes6,"Vr")

            plot3(app.UIAxes7,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes7,'Vm');ylabel(app.UIAxes7,"Hr");zlabel(app.UIAxes7,"Vn")

            plot3(app.UIAxes8,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes8,'Vm');ylabel(app.UIAxes8,"Hr");zlabel(app.UIAxes8,"Vr")
            
        end

        % Value changed function: nu_mvEditField
        function nu_mvEditFieldValueChanged(app, event)
            app.nu_mv = app.nu_mvEditField.Value;
            
            [t,Y,state_n,stab_match,stability] = mainfunc(app); 
            
            plot(app.UIAxes,t,Y(1,:),"r",t,Y(2,:),"b");xlabel(app.UIAxes,"Time");ylabel(app.UIAxes,"Resting Potential");
            title(app.UIAxes,"Sleep-Wake Switch");xticks(app.UIAxes,0:6:72)
            plot(app.UIAxes2,t,Y(3,:),"y",t,Y(4,:),"g");xlabel(app.UIAxes2,"Time");ylabel(app.UIAxes2,"Resting Potential");
            title(app.UIAxes2,"REM-NREM Switch");xticks(app.UIAxes2,0:6:72)
            plot(app.UIAxes3,t,state_n);set(app.UIAxes3,'YDir','reverse');yticks(app.UIAxes3,[1,2,3]);yticklabels(app.UIAxes3,{'Wake', 'REM','NREM'});
            xlabel(app.UIAxes3,"Time");ylabel(app.UIAxes3,"Sleep State");title(app.UIAxes3,"Hypnogram (Sleep State over Time)");xticks(app.UIAxes3,0:6:72)

            plot3(app.UIAxes5,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes5,'Vv');ylabel(app.UIAxes5,"Hr");zlabel(app.UIAxes5,"Vn")

            plot3(app.UIAxes6,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes6,'Vv');ylabel(app.UIAxes6,"Hr");zlabel(app.UIAxes6,"Vr")

            plot3(app.UIAxes7,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes7,'Vm');ylabel(app.UIAxes7,"Hr");zlabel(app.UIAxes7,"Vn")

            plot3(app.UIAxes8,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes8,'Vm');ylabel(app.UIAxes8,"Hr");zlabel(app.UIAxes8,"Vr")
            
        end

        % Value changed function: AmAChandOrexinEditField
        function AmAChandOrexinEditFieldValueChanged(app, event)
            app.Am = app.AmAChandOrexinEditField.Value;
            
            [t,Y,state_n,stab_match,stability] = mainfunc(app); 
            
            plot(app.UIAxes,t,Y(1,:),"r",t,Y(2,:),"b");xlabel(app.UIAxes,"Time");ylabel(app.UIAxes,"Resting Potential");
            title(app.UIAxes,"Sleep-Wake Switch");xticks(app.UIAxes,0:6:72)
            plot(app.UIAxes2,t,Y(3,:),"y",t,Y(4,:),"g");xlabel(app.UIAxes2,"Time");ylabel(app.UIAxes2,"Resting Potential");
            title(app.UIAxes2,"REM-NREM Switch");xticks(app.UIAxes2,0:6:72)
            plot(app.UIAxes3,t,state_n);set(app.UIAxes3,'YDir','reverse');yticks(app.UIAxes3,[1,2,3]);yticklabels(app.UIAxes3,{'Wake', 'REM','NREM'});
            xlabel(app.UIAxes3,"Time");ylabel(app.UIAxes3,"Sleep State");title(app.UIAxes3,"Hypnogram (Sleep State over Time)");xticks(app.UIAxes3,0:6:72)
            
            plot3(app.UIAxes5,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes5,'Vv');ylabel(app.UIAxes5,"Hr");zlabel(app.UIAxes5,"Vn")

            plot3(app.UIAxes6,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes6,'Vv');ylabel(app.UIAxes6,"Hr");zlabel(app.UIAxes6,"Vr")

            plot3(app.UIAxes7,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes7,'Vm');ylabel(app.UIAxes7,"Hr");zlabel(app.UIAxes7,"Vn")

            plot3(app.UIAxes8,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes8,'Vm');ylabel(app.UIAxes8,"Hr");zlabel(app.UIAxes8,"Vr")
            
        end

        % Value changed function: nu_vmEditField
        function nu_vmEditFieldValueChanged(app, event)
            app.nu_vm = app.nu_vmEditField.Value;
            
            [t,Y,state_n,stab_match,stability] = mainfunc(app); 
            
            plot(app.UIAxes,t,Y(1,:),"r",t,Y(2,:),"b");xlabel(app.UIAxes,"Time");ylabel(app.UIAxes,"Resting Potential");
            title(app.UIAxes,"Sleep-Wake Switch");xticks(app.UIAxes,0:6:72)
            plot(app.UIAxes2,t,Y(3,:),"y",t,Y(4,:),"g");xlabel(app.UIAxes2,"Time");ylabel(app.UIAxes2,"Resting Potential");
            title(app.UIAxes2,"REM-NREM Switch");xticks(app.UIAxes2,0:6:72)
            plot(app.UIAxes3,t,state_n);set(app.UIAxes3,'YDir','reverse');yticks(app.UIAxes3,[1,2,3]);yticklabels(app.UIAxes3,{'Wake', 'REM','NREM'});
            xlabel(app.UIAxes3,"Time");ylabel(app.UIAxes3,"Sleep State");title(app.UIAxes3,"Hypnogram (Sleep State over Time)");xticks(app.UIAxes3,0:6:72)

            plot3(app.UIAxes5,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes5,'Vv');ylabel(app.UIAxes5,"Hr");zlabel(app.UIAxes5,"Vn")

            plot3(app.UIAxes6,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes6,'Vv');ylabel(app.UIAxes6,"Hr");zlabel(app.UIAxes6,"Vr")

            plot3(app.UIAxes7,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes7,'Vm');ylabel(app.UIAxes7,"Hr");zlabel(app.UIAxes7,"Vn")

            plot3(app.UIAxes8,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes8,'Vm');ylabel(app.UIAxes8,"Hr");zlabel(app.UIAxes8,"Vr")
            
        end

        % Value changed function: nu_vhEditField
        function nu_vhEditFieldValueChanged(app, event)
            app.nu_vh = app.nu_vhEditField.Value;
            
            [t,Y,state_n,stab_match,stability] = mainfunc(app); 
            
            plot(app.UIAxes,t,Y(1,:),"r",t,Y(2,:),"b");xlabel(app.UIAxes,"Time");ylabel(app.UIAxes,"Resting Potential");
            title(app.UIAxes,"Sleep-Wake Switch");xticks(app.UIAxes,0:6:72)
            plot(app.UIAxes2,t,Y(3,:),"y",t,Y(4,:),"g");xlabel(app.UIAxes2,"Time");ylabel(app.UIAxes2,"Resting Potential");
            title(app.UIAxes2,"REM-NREM Switch");xticks(app.UIAxes2,0:6:72)
            plot(app.UIAxes3,t,state_n);set(app.UIAxes3,'YDir','reverse');yticks(app.UIAxes3,[1,2,3]);yticklabels(app.UIAxes3,{'Wake', 'REM','NREM'});
            xlabel(app.UIAxes3,"Time");ylabel(app.UIAxes3,"Sleep State");title(app.UIAxes3,"Hypnogram (Sleep State over Time)");xticks(app.UIAxes3,0:6:72)

            plot3(app.UIAxes5,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes5,'Vv');ylabel(app.UIAxes5,"Hr");zlabel(app.UIAxes5,"Vn")

            plot3(app.UIAxes6,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes6,'Vv');ylabel(app.UIAxes6,"Hr");zlabel(app.UIAxes6,"Vr")

            plot3(app.UIAxes7,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes7,'Vm');ylabel(app.UIAxes7,"Hr");zlabel(app.UIAxes7,"Vn")

            plot3(app.UIAxes8,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes8,'Vm');ylabel(app.UIAxes8,"Hr");zlabel(app.UIAxes8,"Vr")
            
        end

        % Value changed function: nu_vcEditField
        function nu_vcEditFieldValueChanged(app, event)
            app.nu_vc = app.nu_vcEditField.Value;
            
            [t,Y,state_n,stab_match,stability] = mainfunc(app); 
            
            plot(app.UIAxes,t,Y(1,:),"r",t,Y(2,:),"b");xlabel(app.UIAxes,"Time");ylabel(app.UIAxes,"Resting Potential");
            title(app.UIAxes,"Sleep-Wake Switch");xticks(app.UIAxes,0:6:72)
            plot(app.UIAxes2,t,Y(3,:),"y",t,Y(4,:),"g");xlabel(app.UIAxes2,"Time");ylabel(app.UIAxes2,"Resting Potential");
            title(app.UIAxes2,"REM-NREM Switch");xticks(app.UIAxes2,0:6:72)
            plot(app.UIAxes3,t,state_n);set(app.UIAxes3,'YDir','reverse');yticks(app.UIAxes3,[1,2,3]);yticklabels(app.UIAxes3,{'Wake', 'REM','NREM'});
            xlabel(app.UIAxes3,"Time");ylabel(app.UIAxes3,"Sleep State");title(app.UIAxes3,"Hypnogram (Sleep State over Time)");xticks(app.UIAxes3,0:6:72)

            plot3(app.UIAxes5,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes5,'Vv');ylabel(app.UIAxes5,"Hr");zlabel(app.UIAxes5,"Vn")

            plot3(app.UIAxes6,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes6,'Vv');ylabel(app.UIAxes6,"Hr");zlabel(app.UIAxes6,"Vr")

            plot3(app.UIAxes7,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes7,'Vm');ylabel(app.UIAxes7,"Hr");zlabel(app.UIAxes7,"Vn")

            plot3(app.UIAxes8,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes8,'Vm');ylabel(app.UIAxes8,"Hr");zlabel(app.UIAxes8,"Vr")
            
        end

        % Value changed function: D_0EditField
        function D_0EditFieldValueChanged(app, event)
            app.D_0 = app.D_0EditField.Value;
            
            [t,Y,state_n,stab_match,stability] = mainfunc(app); 
            
            plot(app.UIAxes,t,Y(1,:),"r",t,Y(2,:),"b");xlabel(app.UIAxes,"Time");ylabel(app.UIAxes,"Resting Potential");
            title(app.UIAxes,"Sleep-Wake Switch");xticks(app.UIAxes,0:6:72)
            plot(app.UIAxes2,t,Y(3,:),"y",t,Y(4,:),"g");xlabel(app.UIAxes2,"Time");ylabel(app.UIAxes2,"Resting Potential");
            title(app.UIAxes2,"REM-NREM Switch");xticks(app.UIAxes2,0:6:72)
            plot(app.UIAxes3,t,state_n);set(app.UIAxes3,'YDir','reverse');yticks(app.UIAxes3,[1,2,3]);yticklabels(app.UIAxes3,{'Wake', 'REM','NREM'});
            xlabel(app.UIAxes3,"Time");ylabel(app.UIAxes3,"Sleep State");title(app.UIAxes3,"Hypnogram (Sleep State over Time)");xticks(app.UIAxes3,0:6:72)

            plot3(app.UIAxes5,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes5,'Vv');ylabel(app.UIAxes5,"Hr");zlabel(app.UIAxes5,"Vn")

            plot3(app.UIAxes6,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes6,'Vv');ylabel(app.UIAxes6,"Hr");zlabel(app.UIAxes6,"Vr")

            plot3(app.UIAxes7,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes7,'Vm');ylabel(app.UIAxes7,"Hr");zlabel(app.UIAxes7,"Vn")

            plot3(app.UIAxes8,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes8,'Vm');ylabel(app.UIAxes8,"Hr");zlabel(app.UIAxes8,"Vr")
            
        end

        % Value changed function: chi_vEditField
        function chi_vEditFieldValueChanged(app, event)
            app.chi_v = app.chi_vEditField.Value;
            
            [t,Y,state_n,stab_match,stability] = mainfunc(app); 
            
            plot(app.UIAxes,t,Y(1,:),"r",t,Y(2,:),"b");xlabel(app.UIAxes,"Time");ylabel(app.UIAxes,"Resting Potential");
            title(app.UIAxes,"Sleep-Wake Switch");xticks(app.UIAxes,0:6:72)
            plot(app.UIAxes2,t,Y(3,:),"y",t,Y(4,:),"g");xlabel(app.UIAxes2,"Time");ylabel(app.UIAxes2,"Resting Potential");
            title(app.UIAxes2,"REM-NREM Switch");xticks(app.UIAxes2,0:6:72)
            plot(app.UIAxes3,t,state_n);set(app.UIAxes3,'YDir','reverse');yticks(app.UIAxes3,[1,2,3]);yticklabels(app.UIAxes3,{'Wake', 'REM','NREM'});
            xlabel(app.UIAxes3,"Time");ylabel(app.UIAxes3,"Sleep State");title(app.UIAxes3,"Hypnogram (Sleep State over Time)");xticks(app.UIAxes3,0:6:72)

            plot3(app.UIAxes5,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes5,'Vv');ylabel(app.UIAxes5,"Hr");zlabel(app.UIAxes5,"Vn")

            plot3(app.UIAxes6,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes6,'Vv');ylabel(app.UIAxes6,"Hr");zlabel(app.UIAxes6,"Vr")

            plot3(app.UIAxes7,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes7,'Vm');ylabel(app.UIAxes7,"Hr");zlabel(app.UIAxes7,"Vn")

            plot3(app.UIAxes8,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes8,'Vm');ylabel(app.UIAxes8,"Hr");zlabel(app.UIAxes8,"Vr")
            
        end

        % Value changed function: mu_vmEditField
        function mu_vmEditFieldValueChanged(app, event)
            app.mu_vm = app.mu_vmEditField.Value;
            
            [t,Y,state_n,stab_match,stability] = mainfunc(app); 
            
            plot(app.UIAxes,t,Y(1,:),"r",t,Y(2,:),"b");xlabel(app.UIAxes,"Time");ylabel(app.UIAxes,"Resting Potential");
            title(app.UIAxes,"Sleep-Wake Switch");xticks(app.UIAxes,0:6:72)
            plot(app.UIAxes2,t,Y(3,:),"y",t,Y(4,:),"g");xlabel(app.UIAxes2,"Time");ylabel(app.UIAxes2,"Resting Potential");
            title(app.UIAxes2,"REM-NREM Switch");xticks(app.UIAxes2,0:6:72)
            plot(app.UIAxes3,t,state_n);set(app.UIAxes3,'YDir','reverse');yticks(app.UIAxes3,[1,2,3]);yticklabels(app.UIAxes3,{'Wake', 'REM','NREM'});
            xlabel(app.UIAxes3,"Time");ylabel(app.UIAxes3,"Sleep State");title(app.UIAxes3,"Hypnogram (Sleep State over Time)");xticks(app.UIAxes3,0:6:72)

            plot3(app.UIAxes5,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes5,'Vv');ylabel(app.UIAxes5,"Hr");zlabel(app.UIAxes5,"Vn")

            plot3(app.UIAxes6,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes6,'Vv');ylabel(app.UIAxes6,"Hr");zlabel(app.UIAxes6,"Vr")

            plot3(app.UIAxes7,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes7,'Vm');ylabel(app.UIAxes7,"Hr");zlabel(app.UIAxes7,"Vn")

            plot3(app.UIAxes8,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes8,'Vm');ylabel(app.UIAxes8,"Hr");zlabel(app.UIAxes8,"Vr")
            
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            app.b = app.bEditField.Value;
            
            [t,Y,state_n,stab_match,stability] = mainfunc(app); 
            
            plot(app.UIAxes,t,Y(1,:),"r",t,Y(2,:),"b");xlabel(app.UIAxes,"Time");ylabel(app.UIAxes,"Resting Potential");
            title(app.UIAxes,"Sleep-Wake Switch");xticks(app.UIAxes,0:6:72)
            plot(app.UIAxes2,t,Y(3,:),"y",t,Y(4,:),"g");xlabel(app.UIAxes2,"Time");ylabel(app.UIAxes2,"Resting Potential");
            title(app.UIAxes2,"REM-NREM Switch");xticks(app.UIAxes2,0:6:72)
            plot(app.UIAxes3,t,state_n);set(app.UIAxes3,'YDir','reverse');yticks(app.UIAxes3,[1,2,3]);yticklabels(app.UIAxes3,{'Wake', 'REM','NREM'});
            xlabel(app.UIAxes3,"Time");ylabel(app.UIAxes3,"Sleep State");title(app.UIAxes3,"Hypnogram (Sleep State over Time)");xticks(app.UIAxes3,0:6:72)

            plot3(app.UIAxes5,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes5,'Vv');ylabel(app.UIAxes5,"Hr");zlabel(app.UIAxes5,"Vn")

            plot3(app.UIAxes6,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes6,'Vv');ylabel(app.UIAxes6,"Hr");zlabel(app.UIAxes6,"Vr")

            plot3(app.UIAxes7,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes7,'Vm');ylabel(app.UIAxes7,"Hr");zlabel(app.UIAxes7,"Vn")

            plot3(app.UIAxes8,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes8,'Vm');ylabel(app.UIAxes8,"Hr");zlabel(app.UIAxes8,"Vr")
            
        end

        % Value changed function: nu_rnEditField
        function nu_rnEditFieldValueChanged(app, event)
            app.nu_rn = app.nu_rnEditField.Value;
            
            [t,Y,state_n,stab_match,stability] = mainfunc(app); 
            
            plot(app.UIAxes,t,Y(1,:),"r",t,Y(2,:),"b");xlabel(app.UIAxes,"Time");ylabel(app.UIAxes,"Resting Potential");
            title(app.UIAxes,"Sleep-Wake Switch");xticks(app.UIAxes,0:6:72)
            plot(app.UIAxes2,t,Y(3,:),"y",t,Y(4,:),"g");xlabel(app.UIAxes2,"Time");ylabel(app.UIAxes2,"Resting Potential");
            title(app.UIAxes2,"REM-NREM Switch");xticks(app.UIAxes2,0:6:72)
            plot(app.UIAxes3,t,state_n);set(app.UIAxes3,'YDir','reverse');yticks(app.UIAxes3,[1,2,3]);yticklabels(app.UIAxes3,{'Wake', 'REM','NREM'});
            xlabel(app.UIAxes3,"Time");ylabel(app.UIAxes3,"Sleep State");title(app.UIAxes3,"Hypnogram (Sleep State over Time)");xticks(app.UIAxes3,0:6:72)

            plot3(app.UIAxes5,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes5,'Vv');ylabel(app.UIAxes5,"Hr");zlabel(app.UIAxes5,"Vn")

            plot3(app.UIAxes6,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes6,'Vv');ylabel(app.UIAxes6,"Hr");zlabel(app.UIAxes6,"Vr")

            plot3(app.UIAxes7,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes7,'Vm');ylabel(app.UIAxes7,"Hr");zlabel(app.UIAxes7,"Vn")

            plot3(app.UIAxes8,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes8,'Vm');ylabel(app.UIAxes8,"Hr");zlabel(app.UIAxes8,"Vr")
            
        end

        % Value changed function: nu_nvEditField
        function nu_nvEditFieldValueChanged(app, event)
            app.nu_nv = app.nu_nvEditField.Value;
            
            [t,Y,state_n,stab_match,stability] = mainfunc(app); 
            
            plot(app.UIAxes,t,Y(1,:),"r",t,Y(2,:),"b");xlabel(app.UIAxes,"Time");ylabel(app.UIAxes,"Resting Potential");
            title(app.UIAxes,"Sleep-Wake Switch");xticks(app.UIAxes,0:6:72)
            plot(app.UIAxes2,t,Y(3,:),"y",t,Y(4,:),"g");xlabel(app.UIAxes2,"Time");ylabel(app.UIAxes2,"Resting Potential");
            title(app.UIAxes2,"REM-NREM Switch");xticks(app.UIAxes2,0:6:72)
            plot(app.UIAxes3,t,state_n);set(app.UIAxes3,'YDir','reverse');yticks(app.UIAxes3,[1,2,3]);yticklabels(app.UIAxes3,{'Wake', 'REM','NREM'});
            xlabel(app.UIAxes3,"Time");ylabel(app.UIAxes3,"Sleep State");title(app.UIAxes3,"Hypnogram (Sleep State over Time)");xticks(app.UIAxes3,0:6:72)

            plot3(app.UIAxes5,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes5,'Vv');ylabel(app.UIAxes5,"Hr");zlabel(app.UIAxes5,"Vn")

            plot3(app.UIAxes6,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes6,'Vv');ylabel(app.UIAxes6,"Hr");zlabel(app.UIAxes6,"Vr")

            plot3(app.UIAxes7,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes7,'Vm');ylabel(app.UIAxes7,"Hr");zlabel(app.UIAxes7,"Vn")

            plot3(app.UIAxes8,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes8,'Vm');ylabel(app.UIAxes8,"Hr");zlabel(app.UIAxes8,"Vr")
            
        end

        % Value changed function: nu_nrEditField
        function nu_nrEditFieldValueChanged(app, event)
            app.nu_nr = app.nu_nrEditField.Value;
            
            [t,Y,state_n,stab_match,stability] = mainfunc(app); 
            
            plot(app.UIAxes,t,Y(1,:),"r",t,Y(2,:),"b");xlabel(app.UIAxes,"Time");ylabel(app.UIAxes,"Resting Potential");
            title(app.UIAxes,"Sleep-Wake Switch");xticks(app.UIAxes,0:6:72)
            plot(app.UIAxes2,t,Y(3,:),"y",t,Y(4,:),"g");xlabel(app.UIAxes2,"Time");ylabel(app.UIAxes2,"Resting Potential");
            title(app.UIAxes2,"REM-NREM Switch");xticks(app.UIAxes2,0:6:72)
            plot(app.UIAxes3,t,state_n);set(app.UIAxes3,'YDir','reverse');yticks(app.UIAxes3,[1,2,3]);yticklabels(app.UIAxes3,{'Wake', 'REM','NREM'});
            xlabel(app.UIAxes3,"Time");ylabel(app.UIAxes3,"Sleep State");title(app.UIAxes3,"Hypnogram (Sleep State over Time)");xticks(app.UIAxes3,0:6:72)

            plot3(app.UIAxes5,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes5,'Vv');ylabel(app.UIAxes5,"Hr");zlabel(app.UIAxes5,"Vn")

            plot3(app.UIAxes6,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes6,'Vv');ylabel(app.UIAxes6,"Hr");zlabel(app.UIAxes6,"Vr")

            plot3(app.UIAxes7,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes7,'Vm');ylabel(app.UIAxes7,"Hr");zlabel(app.UIAxes7,"Vn")

            plot3(app.UIAxes8,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes8,'Vm');ylabel(app.UIAxes8,"Hr");zlabel(app.UIAxes8,"Vr")
            
        end

        % Value changed function: nu_rmEditField
        function nu_rmEditFieldValueChanged(app, event)
            app.nu_rm = app.nu_rmEditField.Value;
            
            [t,Y,state_n,stab_match,stability] = mainfunc(app); 
            
            plot(app.UIAxes,t,Y(1,:),"r",t,Y(2,:),"b");xlabel(app.UIAxes,"Time");ylabel(app.UIAxes,"Resting Potential");
            title(app.UIAxes,"Sleep-Wake Switch");xticks(app.UIAxes,0:6:72)
            plot(app.UIAxes2,t,Y(3,:),"y",t,Y(4,:),"g");xlabel(app.UIAxes2,"Time");ylabel(app.UIAxes2,"Resting Potential");
            title(app.UIAxes2,"REM-NREM Switch");xticks(app.UIAxes2,0:6:72)
            plot(app.UIAxes3,t,state_n);set(app.UIAxes3,'YDir','reverse');yticks(app.UIAxes3,[1,2,3]);yticklabels(app.UIAxes3,{'Wake', 'REM','NREM'});
            xlabel(app.UIAxes3,"Time");ylabel(app.UIAxes3,"Sleep State");title(app.UIAxes3,"Hypnogram (Sleep State over Time)");xticks(app.UIAxes3,0:6:72)

            plot3(app.UIAxes5,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes5,'Vv');ylabel(app.UIAxes5,"Hr");zlabel(app.UIAxes5,"Vn")

            plot3(app.UIAxes6,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes6,'Vv');ylabel(app.UIAxes6,"Hr");zlabel(app.UIAxes6,"Vr")

            plot3(app.UIAxes7,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes7,'Vm');ylabel(app.UIAxes7,"Hr");zlabel(app.UIAxes7,"Vn")

            plot3(app.UIAxes8,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes8,'Vm');ylabel(app.UIAxes8,"Hr");zlabel(app.UIAxes8,"Vr")
            
        end

        % Value changed function: nu_nmEditField
        function nu_nmEditFieldValueChanged(app, event)
            app.nu_nm = app.nu_nmEditField.Value;
            
            [t,Y,state_n,stab_match,stability] = mainfunc(app); 
            
            plot(app.UIAxes,t,Y(1,:),"r",t,Y(2,:),"b");xlabel(app.UIAxes,"Time");ylabel(app.UIAxes,"Resting Potential");
            title(app.UIAxes,"Sleep-Wake Switch");xticks(app.UIAxes,0:6:72)
            plot(app.UIAxes2,t,Y(3,:),"y",t,Y(4,:),"g");xlabel(app.UIAxes2,"Time");ylabel(app.UIAxes2,"Resting Potential");
            title(app.UIAxes2,"REM-NREM Switch");xticks(app.UIAxes2,0:6:72)
            plot(app.UIAxes3,t,state_n);set(app.UIAxes3,'YDir','reverse');yticks(app.UIAxes3,[1,2,3]);yticklabels(app.UIAxes3,{'Wake', 'REM','NREM'});
            xlabel(app.UIAxes3,"Time");ylabel(app.UIAxes3,"Sleep State");title(app.UIAxes3,"Hypnogram (Sleep State over Time)");xticks(app.UIAxes3,0:6:72)

            plot3(app.UIAxes5,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes5,'Vv');ylabel(app.UIAxes5,"Hr");zlabel(app.UIAxes5,"Vn")

            plot3(app.UIAxes6,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes6,'Vv');ylabel(app.UIAxes6,"Hr");zlabel(app.UIAxes6,"Vr")

            plot3(app.UIAxes7,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes7,'Vm');ylabel(app.UIAxes7,"Hr");zlabel(app.UIAxes7,"Vn")

            plot3(app.UIAxes8,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes8,'Vm');ylabel(app.UIAxes8,"Hr");zlabel(app.UIAxes8,"Vr")
            
        end

        % Value changed function: mu_rnEditField
        function mu_rnEditFieldValueChanged(app, event)
            app.mu_rn = app.mu_rnEditField.Value;
            
            [t,Y,state_n,stab_match,stability] = mainfunc(app); 
            
            plot(app.UIAxes,t,Y(1,:),"r",t,Y(2,:),"b");xlabel(app.UIAxes,"Time");ylabel(app.UIAxes,"Resting Potential");
            title(app.UIAxes,"Sleep-Wake Switch");xticks(app.UIAxes,0:6:72)
            plot(app.UIAxes2,t,Y(3,:),"y",t,Y(4,:),"g");xlabel(app.UIAxes2,"Time");ylabel(app.UIAxes2,"Resting Potential");
            title(app.UIAxes2,"REM-NREM Switch");xticks(app.UIAxes2,0:6:72)
            plot(app.UIAxes3,t,state_n);set(app.UIAxes3,'YDir','reverse');yticks(app.UIAxes3,[1,2,3]);yticklabels(app.UIAxes3,{'Wake', 'REM','NREM'});
            xlabel(app.UIAxes3,"Time");ylabel(app.UIAxes3,"Sleep State");title(app.UIAxes3,"Hypnogram (Sleep State over Time)");xticks(app.UIAxes3,0:6:72)

            plot3(app.UIAxes5,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes5,'Vv');ylabel(app.UIAxes5,"Hr");zlabel(app.UIAxes5,"Vn")

            plot3(app.UIAxes6,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes6,'Vv');ylabel(app.UIAxes6,"Hr");zlabel(app.UIAxes6,"Vr")

            plot3(app.UIAxes7,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes7,'Vm');ylabel(app.UIAxes7,"Hr");zlabel(app.UIAxes7,"Vn")

            plot3(app.UIAxes8,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes8,'Vm');ylabel(app.UIAxes8,"Hr");zlabel(app.UIAxes8,"Vr")
            
        end

        % Value changed function: tau_rnEditField
        function tau_rnEditFieldValueChanged(app, event)
            app.tau_rn = app.tau_rnEditField.Value;
            
            [t,Y,state_n,stab_match,stability] = mainfunc(app); 
            
            plot(app.UIAxes,t,Y(1,:),"r",t,Y(2,:),"b");xlabel(app.UIAxes,"Time");ylabel(app.UIAxes,"Resting Potential");
            title(app.UIAxes,"Sleep-Wake Switch");xticks(app.UIAxes,0:6:72)
            plot(app.UIAxes2,t,Y(3,:),"y",t,Y(4,:),"g");xlabel(app.UIAxes2,"Time");ylabel(app.UIAxes2,"Resting Potential");
            title(app.UIAxes2,"REM-NREM Switch");xticks(app.UIAxes2,0:6:72)
            plot(app.UIAxes3,t,state_n);set(app.UIAxes3,'YDir','reverse');yticks(app.UIAxes3,[1,2,3]);yticklabels(app.UIAxes3,{'Wake', 'REM','NREM'});
            xlabel(app.UIAxes3,"Time");ylabel(app.UIAxes3,"Sleep State");title(app.UIAxes3,"Hypnogram (Sleep State over Time)");xticks(app.UIAxes3,0:6:72)

            plot3(app.UIAxes5,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes5,'Vv');ylabel(app.UIAxes5,"Hr");zlabel(app.UIAxes5,"Vn")

            plot3(app.UIAxes6,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes6,'Vv');ylabel(app.UIAxes6,"Hr");zlabel(app.UIAxes6,"Vr")

            plot3(app.UIAxes7,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes7,'Vm');ylabel(app.UIAxes7,"Hr");zlabel(app.UIAxes7,"Vn")

            plot3(app.UIAxes8,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes8,'Vm');ylabel(app.UIAxes8,"Hr");zlabel(app.UIAxes8,"Vr")
            
        end

        % Value changed function: chi_rEditField
        function chi_rEditFieldValueChanged(app, event)
            app.chi_r = app.chi_rEditField.Value;
            
            [t,Y,state_n,stab_match,stability] = mainfunc(app); 
            
            plot(app.UIAxes,t,Y(1,:),"r",t,Y(2,:),"b");xlabel(app.UIAxes,"Time");ylabel(app.UIAxes,"Resting Potential");
            title(app.UIAxes,"Sleep-Wake Switch");xticks(app.UIAxes,0:6:72)
            plot(app.UIAxes2,t,Y(3,:),"y",t,Y(4,:),"g");xlabel(app.UIAxes2,"Time");ylabel(app.UIAxes2,"Resting Potential");
            title(app.UIAxes2,"REM-NREM Switch");xticks(app.UIAxes2,0:6:72)
            plot(app.UIAxes3,t,state_n);set(app.UIAxes3,'YDir','reverse');yticks(app.UIAxes3,[1,2,3]);yticklabels(app.UIAxes3,{'Wake', 'REM','NREM'});
            xlabel(app.UIAxes3,"Time");ylabel(app.UIAxes3,"Sleep State");title(app.UIAxes3,"Hypnogram (Sleep State over Time)");xticks(app.UIAxes3,0:6:72)

            plot3(app.UIAxes5,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes5,'Vv');ylabel(app.UIAxes5,"Hr");zlabel(app.UIAxes5,"Vn")

            plot3(app.UIAxes6,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes6,'Vv');ylabel(app.UIAxes6,"Hr");zlabel(app.UIAxes6,"Vr")

            plot3(app.UIAxes7,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes7,'Vm');ylabel(app.UIAxes7,"Hr");zlabel(app.UIAxes7,"Vn")

            plot3(app.UIAxes8,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes8,'Vm');ylabel(app.UIAxes8,"Hr");zlabel(app.UIAxes8,"Vr")
            
        end

        % Value changed function: nu_rhrEditField
        function nu_rhrEditFieldValueChanged(app, event)
            app.nu_rhr = app.nu_rhrEditField.Value;
            
            [t,Y,state_n,stab_match,stability] = mainfunc(app); 
            
            plot(app.UIAxes,t,Y(1,:),"r",t,Y(2,:),"b");xlabel(app.UIAxes,"Time");ylabel(app.UIAxes,"Resting Potential");
            title(app.UIAxes,"Sleep-Wake Switch");xticks(app.UIAxes,0:6:72)
            plot(app.UIAxes2,t,Y(3,:),"y",t,Y(4,:),"g");xlabel(app.UIAxes2,"Time");ylabel(app.UIAxes2,"Resting Potential");
            title(app.UIAxes2,"REM-NREM Switch");xticks(app.UIAxes2,0:6:72)
            plot(app.UIAxes3,t,state_n);set(app.UIAxes3,'YDir','reverse');yticks(app.UIAxes3,[1,2,3]);yticklabels(app.UIAxes3,{'Wake', 'REM','NREM'});
            xlabel(app.UIAxes3,"Time");ylabel(app.UIAxes3,"Sleep State");title(app.UIAxes3,"Hypnogram (Sleep State over Time)");xticks(app.UIAxes3,0:6:72)

            plot3(app.UIAxes5,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes5,'Vv');ylabel(app.UIAxes5,"Hr");zlabel(app.UIAxes5,"Vn")

            plot3(app.UIAxes6,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes6,'Vv');ylabel(app.UIAxes6,"Hr");zlabel(app.UIAxes6,"Vr")

            plot3(app.UIAxes7,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes7,'Vm');ylabel(app.UIAxes7,"Hr");zlabel(app.UIAxes7,"Vn")

            plot3(app.UIAxes8,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes8,'Vm');ylabel(app.UIAxes8,"Hr");zlabel(app.UIAxes8,"Vr")
            
        end

        % Value changed function: DR_0EditField
        function DR_0EditFieldValueChanged(app, event)
            app.DR_0 = app.DR_0EditField.Value;
            
            [t,Y,state_n,stab_match,stability] = mainfunc(app); 
            
            plot(app.UIAxes,t,Y(1,:),"r",t,Y(2,:),"b");xlabel(app.UIAxes,"Time");ylabel(app.UIAxes,"Resting Potential");
            title(app.UIAxes,"Sleep-Wake Switch");xticks(app.UIAxes,0:6:72)
            plot(app.UIAxes2,t,Y(3,:),"y",t,Y(4,:),"g");xlabel(app.UIAxes2,"Time");ylabel(app.UIAxes2,"Resting Potential");
            title(app.UIAxes2,"REM-NREM Switch");xticks(app.UIAxes2,0:6:72)
            plot(app.UIAxes3,t,state_n);set(app.UIAxes3,'YDir','reverse');yticks(app.UIAxes3,[1,2,3]);yticklabels(app.UIAxes3,{'Wake', 'REM','NREM'});
            xlabel(app.UIAxes3,"Time");ylabel(app.UIAxes3,"Sleep State");title(app.UIAxes3,"Hypnogram (Sleep State over Time)");xticks(app.UIAxes3,0:6:72)

            plot3(app.UIAxes5,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes5,'Vv');ylabel(app.UIAxes5,"Hr");zlabel(app.UIAxes5,"Vn")

            plot3(app.UIAxes6,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes6,'Vv');ylabel(app.UIAxes6,"Hr");zlabel(app.UIAxes6,"Vr")

            plot3(app.UIAxes7,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes7,'Vm');ylabel(app.UIAxes7,"Hr");zlabel(app.UIAxes7,"Vn")

            plot3(app.UIAxes8,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes8,'Vm');ylabel(app.UIAxes8,"Hr");zlabel(app.UIAxes8,"Vr")
            
        end

        % Value changed function: mu_rmEditField
        function mu_rmEditFieldValueChanged(app, event)
            app.mu_rm = app.mu_rmEditField.Value;
            
            [t,Y,state_n,stab_match,stability] = mainfunc(app); 
            
            plot(app.UIAxes,t,Y(1,:),"r",t,Y(2,:),"b");xlabel(app.UIAxes,"Time");ylabel(app.UIAxes,"Resting Potential");
            title(app.UIAxes,"Sleep-Wake Switch");xticks(app.UIAxes,0:6:72)
            plot(app.UIAxes2,t,Y(3,:),"y",t,Y(4,:),"g");xlabel(app.UIAxes2,"Time");ylabel(app.UIAxes2,"Resting Potential");
            title(app.UIAxes2,"REM-NREM Switch");xticks(app.UIAxes2,0:6:72)
            plot(app.UIAxes3,t,state_n);set(app.UIAxes3,'YDir','reverse');yticks(app.UIAxes3,[1,2,3]);yticklabels(app.UIAxes3,{'Wake', 'REM','NREM'});
            xlabel(app.UIAxes3,"Time");ylabel(app.UIAxes3,"Sleep State");title(app.UIAxes3,"Hypnogram (Sleep State over Time)");xticks(app.UIAxes3,0:6:72)

            plot3(app.UIAxes5,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes5,'Vv');ylabel(app.UIAxes5,"Hr");zlabel(app.UIAxes5,"Vn")

            plot3(app.UIAxes6,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes6,'Vv');ylabel(app.UIAxes6,"Hr");zlabel(app.UIAxes6,"Vr")

            plot3(app.UIAxes7,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes7,'Vm');ylabel(app.UIAxes7,"Hr");zlabel(app.UIAxes7,"Vn")

            plot3(app.UIAxes8,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes8,'Vm');ylabel(app.UIAxes8,"Hr");zlabel(app.UIAxes8,"Vr")
            
        end

        % Value changed function: DN_0EditField
        function DN_0EditFieldValueChanged(app, event)
            app.DN_0 = app.DN_0EditField.Value;
            
            [t,Y,state_n,stab_match,stability] = mainfunc(app); 
            
            plot(app.UIAxes,t,Y(1,:),"r",t,Y(2,:),"b");xlabel(app.UIAxes,"Time");ylabel(app.UIAxes,"Resting Potential");
            title(app.UIAxes,"Sleep-Wake Switch");xticks(app.UIAxes,0:6:72)
            plot(app.UIAxes2,t,Y(3,:),"y",t,Y(4,:),"g");xlabel(app.UIAxes2,"Time");ylabel(app.UIAxes2,"Resting Potential");
            title(app.UIAxes2,"REM-NREM Switch");xticks(app.UIAxes2,0:6:72)
            plot(app.UIAxes3,t,state_n);set(app.UIAxes3,'YDir','reverse');yticks(app.UIAxes3,[1,2,3]);yticklabels(app.UIAxes3,{'Wake', 'REM','NREM'});
            xlabel(app.UIAxes3,"Time");ylabel(app.UIAxes3,"Sleep State");title(app.UIAxes3,"Hypnogram (Sleep State over Time)");xticks(app.UIAxes3,0:6:72)

            plot3(app.UIAxes5,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes5,'Vv');ylabel(app.UIAxes5,"Hr");zlabel(app.UIAxes5,"Vn")

            plot3(app.UIAxes6,stab_match(1,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(1,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes6,'Vv');ylabel(app.UIAxes6,"Hr");zlabel(app.UIAxes6,"Vr")

            plot3(app.UIAxes7,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(3,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(3,stability == 3),'.b');
            xlabel(app.UIAxes7,'Vm');ylabel(app.UIAxes7,"Hr");zlabel(app.UIAxes7,"Vn")

            plot3(app.UIAxes8,stab_match(4,stability == 1), stab_match(2,stability == 1), stab_match(5,stability == 1),'.r',...
            stab_match(4,stability == 3), stab_match(2,stability == 3), stab_match(5,stability == 3),'.b');
            xlabel(app.UIAxes8,'Vm');ylabel(app.UIAxes8,"Hr");zlabel(app.UIAxes8,"Vr")
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.UIFigure);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.UIFigure);
            title(app.UIAxes8, 'Title')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.tau_vmEditFieldLabel = uilabel(app.UIFigure);
            app.tau_vmEditFieldLabel.Text = 'tau_vm';
            app.tau_vmEditField = uieditfield(app.UIFigure, 'numeric');
            app.tau_vmEditField.Limits = [1 20];
            app.tau_vmEditField.ValueChangedFcn = createCallbackFcn(app, @tau_vmEditFieldValueChanged, true);
            app.tau_vmEditField.Value = 10;
            app.nu_mvEditFieldLabel = uilabel(app.UIFigure);
            app.nu_mvEditFieldLabel.Text = 'nu_mv';
            app.nu_mvEditField = uieditfield(app.UIFigure, 'numeric');
            app.nu_mvEditField.Limits = [-2.5 0];
            app.nu_mvEditField.ValueChangedFcn = createCallbackFcn(app, @nu_mvEditFieldValueChanged, true);
            app.nu_mvEditField.Value = -1.8;
            app.AmAChandOrexinEditFieldLabel = uilabel(app.UIFigure);
            app.AmAChandOrexinEditFieldLabel.Text = 'Am (ACh and Orexin)';
            app.AmAChandOrexinEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmAChandOrexinEditField.Limits = [0 1.5];
            app.AmAChandOrexinEditField.ValueChangedFcn = createCallbackFcn(app, @AmAChandOrexinEditFieldValueChanged, true);
            app.AmAChandOrexinEditField.Value = 1.3;
            app.nu_vmEditFieldLabel = uilabel(app.UIFigure);
            app.nu_vmEditFieldLabel.Text = 'nu_vm';
            app.nu_vmEditField = uieditfield(app.UIFigure, 'numeric');
            app.nu_vmEditField.ValueChangedFcn = createCallbackFcn(app, @nu_vmEditFieldValueChanged, true);
            app.nu_vmEditField.Value = -2.1;
            app.nu_vhEditFieldLabel = uilabel(app.UIFigure);
            app.nu_vhEditFieldLabel.Text = 'nu_vh';
            app.nu_vhEditField = uieditfield(app.UIFigure, 'numeric');
            app.nu_vhEditField.Limits = [0 5];
            app.nu_vhEditField.ValueChangedFcn = createCallbackFcn(app, @nu_vhEditFieldValueChanged, true);
            app.nu_vhEditField.Value = 1;
            app.nu_vcEditFieldLabel = uilabel(app.UIFigure);
            app.nu_vcEditFieldLabel.Text = 'nu_vc';
            app.nu_vcEditField = uieditfield(app.UIFigure, 'numeric');
            app.nu_vcEditField.Limits = [-4.5 0];
            app.nu_vcEditField.ValueChangedFcn = createCallbackFcn(app, @nu_vcEditFieldValueChanged, true);
            app.nu_vcEditField.Value = -3.37;
            app.D_0EditFieldLabel = uilabel(app.UIFigure);
            app.D_0EditFieldLabel.Text = 'D_0';
            app.D_0EditField = uieditfield(app.UIFigure, 'numeric');
            app.D_0EditField.Limits = [-11 0];
            app.D_0EditField.ValueChangedFcn = createCallbackFcn(app, @D_0EditFieldValueChanged, true);
            app.D_0EditField.Value = -10.2;
            app.chi_vEditFieldLabel = uilabel(app.UIFigure);
            app.chi_vEditFieldLabel.Text = 'chi_v';
            app.chi_vEditField = uieditfield(app.UIFigure, 'numeric');
            app.chi_vEditField.Limits = [1 60];
            app.chi_vEditField.ValueChangedFcn = createCallbackFcn(app, @chi_vEditFieldValueChanged, true);
            app.chi_vEditField.Value = 45;
            app.mu_vmEditFieldLabel = uilabel(app.UIFigure);
            app.mu_vmEditFieldLabel.Text = 'mu_vm';
            app.mu_vmEditField = uieditfield(app.UIFigure, 'numeric');
            app.mu_vmEditField.ValueChangedFcn = createCallbackFcn(app, @mu_vmEditFieldValueChanged, true);
            app.mu_vmEditField.Value = 4.2;
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField.Limits = [-1 1];
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.bEditField.Value = 0.4;
            app.SleepWakeSwitchLabel = uilabel(app.UIFigure);
            app.SleepWakeSwitchLabel.Text = 'Sleep/Wake Switch';
            app.lightprocessingLabel = uilabel(app.UIFigure);
            app.lightprocessingLabel.Text = 'light processing';
            app.REMNREMSwitchLabel = uilabel(app.UIFigure);
            app.REMNREMSwitchLabel.Text = 'REM/NREM Switch';
            app.nu_rnEditFieldLabel = uilabel(app.UIFigure);
            app.nu_rnEditFieldLabel.Text = 'nu_rn';
            app.nu_rnEditField = uieditfield(app.UIFigure, 'numeric');
            app.nu_rnEditField.ValueChangedFcn = createCallbackFcn(app, @nu_rnEditFieldValueChanged, true);
            app.nu_rnEditField.Value = -1;
            app.nu_nvEditFieldLabel = uilabel(app.UIFigure);
            app.nu_nvEditFieldLabel.Text = 'nu_nv';
            app.nu_nvEditField = uieditfield(app.UIFigure, 'numeric');
            app.nu_nvEditField.Limits = [0 5];
            app.nu_nvEditField.ValueChangedFcn = createCallbackFcn(app, @nu_nvEditFieldValueChanged, true);
            app.nu_nvEditField.Value = 2;
            app.nu_nrEditFieldLabel = uilabel(app.UIFigure);
            app.nu_nrEditFieldLabel.Text = 'nu_nr';
            app.nu_nrEditField = uieditfield(app.UIFigure, 'numeric');
            app.nu_nrEditField.Limits = [-5 0];
            app.nu_nrEditField.ValueChangedFcn = createCallbackFcn(app, @nu_nrEditFieldValueChanged, true);
            app.nu_nrEditField.Value = -1;
            app.nu_rmEditFieldLabel = uilabel(app.UIFigure);
            app.nu_rmEditFieldLabel.Text = 'nu_rm';
            app.nu_rmEditField = uieditfield(app.UIFigure, 'numeric');
            app.nu_rmEditField.Limits = [-5 0];
            app.nu_rmEditField.ValueChangedFcn = createCallbackFcn(app, @nu_rmEditFieldValueChanged, true);
            app.nu_rmEditField.Value = -2;
            app.nu_nmEditFieldLabel = uilabel(app.UIFigure);
            app.nu_nmEditFieldLabel.Text = 'nu_nm';
            app.nu_nmEditField = uieditfield(app.UIFigure, 'numeric');
            app.nu_nmEditField.Limits = [0 5];
            app.nu_nmEditField.ValueChangedFcn = createCallbackFcn(app, @nu_nmEditFieldValueChanged, true);
            app.nu_nmEditField.Value = 0.5;
            app.mu_rnEditFieldLabel = uilabel(app.UIFigure);
            app.mu_rnEditFieldLabel.Text = 'mu_rn';
            app.mu_rnEditField = uieditfield(app.UIFigure, 'numeric');
            app.mu_rnEditField.ValueChangedFcn = createCallbackFcn(app, @mu_rnEditFieldValueChanged, true);
            app.mu_rnEditField.Value = 1;
            app.tau_rnEditFieldLabel = uilabel(app.UIFigure);
            app.tau_rnEditFieldLabel.Text = 'tau_rn';
            app.tau_rnEditField = uieditfield(app.UIFigure, 'numeric');
            app.tau_rnEditField.Limits = [1 20];
            app.tau_rnEditField.ValueChangedFcn = createCallbackFcn(app, @tau_rnEditFieldValueChanged, true);
            app.tau_rnEditField.Value = 10;
            app.REMHDriveLabel = uilabel(app.UIFigure);
            app.REMHDriveLabel.Text = 'REM-H Drive';
            app.chi_rEditFieldLabel = uilabel(app.UIFigure);
            app.chi_rEditFieldLabel.Text = 'chi_r';
            app.chi_rEditField = uieditfield(app.UIFigure, 'numeric');
            app.chi_rEditField.Limits = [0 20];
            app.chi_rEditField.ValueChangedFcn = createCallbackFcn(app, @chi_rEditFieldValueChanged, true);
            app.chi_rEditField.Value = 0.3;
            app.nu_rhrEditFieldLabel = uilabel(app.UIFigure);
            app.nu_rhrEditFieldLabel.Text = 'nu_rhr';
            app.nu_rhrEditField = uieditfield(app.UIFigure, 'numeric');
            app.nu_rhrEditField.Limits = [0 5];
            app.nu_rhrEditField.ValueChangedFcn = createCallbackFcn(app, @nu_rhrEditFieldValueChanged, true);
            app.nu_rhrEditField.Value = 1;
            app.DR_0EditFieldLabel = uilabel(app.UIFigure);
            app.DR_0EditFieldLabel.Text = 'DR_0';
            app.DR_0EditField = uieditfield(app.UIFigure, 'numeric');
            app.DR_0EditField.Limits = [-10 10];
            app.DR_0EditField.ValueChangedFcn = createCallbackFcn(app, @DR_0EditFieldValueChanged, true);
            app.AdditionalREMNREMinputsLabel = uilabel(app.UIFigure);
            app.AdditionalREMNREMinputsLabel.Text = 'Additional REM-NREM inputs';
            app.mu_rmEditFieldLabel = uilabel(app.UIFigure);
            app.mu_rmEditFieldLabel.Text = 'mu_rm';
            app.mu_rmEditField = uieditfield(app.UIFigure, 'numeric');
            app.mu_rmEditField.Limits = [0 5];
            app.mu_rmEditField.ValueChangedFcn = createCallbackFcn(app, @mu_rmEditFieldValueChanged, true);
            app.mu_rmEditField.Value = 0.5;
            app.DN_0EditFieldLabel = uilabel(app.UIFigure);
            app.DN_0EditFieldLabel.Text = 'DN_0';
            app.DN_0EditField = uieditfield(app.UIFigure, 'numeric');
            app.DN_0EditField.Limits = [-10 10];
            app.DN_0EditField.ValueChangedFcn = createCallbackFcn(app, @DN_0EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Full_REM_dblswitch_ecd_mlfs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end